classdef STKplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        STKPlotUIFigure     matlab.ui.Figure
        Panel               matlab.ui.container.Panel
        Image               matlab.ui.control.Image
        RunSTKButton        matlab.ui.control.Button
        plotButton          matlab.ui.control.Button
        yAxisDropDown       matlab.ui.control.DropDown
        yAxisDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        stk     % STK Application object
    root       % STK Root object
    scenario   % STK Scenario object
    satellite  % % Description
    dae
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              app.STKPlotUIFigure.WindowStyle='alwaysontop';
                set(app.plotButton,'Enable','off'); 
        end

        % Button pushed function: RunSTKButton
        function RunSTKButtonPushed(app, event)
          
            file='Files/ali.orbit';
            f=fileread(file);
            a=str2double( regexp(f,'(?<=a=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            e=str2double( regexp(f,'(?<=e=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            incl=str2double( regexp(f,'(?<=i=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            w=str2double( regexp(f,'(?<=w=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            TA=str2double( regexp(f,'(?<=TA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            RA=str2double( regexp(f,'(?<=RA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            stop=str2double( regexp(f,'(?<=s=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            y=str2double( regexp(f,'(?<=y=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            m=str2double( regexp(f,'(?<=m=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            d=str2double( regexp(f,'(?<=d=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            hu=str2double( regexp(f,'(?<=h=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            mi=str2double( regexp(f,'(?<=mn=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            sec=str2double( regexp(f,'(?<=sec=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            dateVec=[y m d hu mi sec];
            dateString1 = datestr(dateVec, 'dd mmm yyyy HH:MM:SS.FFF');
            n = sqrt(abs(398600/abs(a^3)));
            T = abs(2*pi/n);
            n_periods=stop;
            TAo = TA*pi/180;
            Eo = 2*atan(tan(TAo/2)*sqrt((1-e)/(1+e)));
            Mo = Eo - e*sin(Eo);
            to = Mo*(T/2/pi);
            tf = to + n_periods*T;
            app.dae=n_periods*T;
            date2=datetime(dateVec)+seconds(n_periods*T);
            dateString2=datestr(date2, 'dd mmm yyyy HH:MM:SS.FFF');

            %%%%%%%%%launch STK
            app.stk = actxserver('STK11.application');
            app.stk.Visible = 1; % Make STK visible

            % Create a new scenario
            app.root = app.stk.Personality2;
            app.root.NewScenario('EgsaMat');
            app.scenario = app.root.CurrentScenario;

            app.scenario.SetTimePeriod(dateString1, dateString2);
            app.scenario.StartTime = dateString1;
            app.scenario.StopTime = dateString2;


            app.satellite = app.scenario.Children.New('eSatellite', 'MatlabSatellite');

% Set the satellite to use the Astrogator propagator
app.satellite.SetPropagatorType('ePropagatorAstrogator');
astrogator = app.satellite.Propagator;
missionControl = astrogator.MainSequence;

% Clear any existing segments
missionControl.RemoveAll();

% Add an Initial State segment
initState = missionControl.Insert('eVASegmentTypeInitialState', 'InitialState', '-');
initState.SetElementType('eVAElementTypeKeplerian');

% Set the initial conditions using Keplerian elements
keplerian = initState.Element;
keplerian.SemiMajorAxis = a; % in kilometers
keplerian.Eccentricity = e;
keplerian.Inclination = incl; % in degrees
keplerian.ArgOfPeriapsis = w; % in degrees
keplerian.RAAN = RA; % in degrees
keplerian.TrueAnomaly = TA; % in degrees

% Set the orbit epoch for the initial state
initState.OrbitEpoch = dateString1;

% Add a Propagate segment
propagate = missionControl.Insert('eVASegmentTypePropagate', 'Propagate', '-');
% propagate.StoppingConditions.Add('Duration');
propagate.StoppingConditions.Item('Duration').Properties.Trip = n_periods*T; % Propagation period in seconds (1 day)

% Run the mission control sequence to propagate the orbit
astrogator.RunMCS();

% Configure the 2D Graphics options to display the entire ground track
app.satellite.Graphics.PassData.Orbit.SetLeadDataType('eDataAll');
app.satellite.Graphics.PassData.Orbit.SetTrailDataType('eDataAll');

% Set the 2D Graphics Pass Intervals to include the entire scenario duration
% Refresh the 2D window to update the display
app.root.UnitPreferences.SetCurrentUnit('DateFormat', 'EpSec');
app.root.Rewind();
  app.root.CurrentTime = n_periods*T-3;
    set(app.plotButton,'Enable','on'); 
      set(app.RunSTKButton,'Enable','off'); 

% app.root.Rewind();

        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            
            file='Files/ali.orbit';
            %             file1='C:\Users\Ali\Desktop\pass.orbit';
            f=fileread(file);
            a=str2double( regexp(f,'(?<=a=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            e=str2double( regexp(f,'(?<=e=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            incl=str2double( regexp(f,'(?<=i=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            w=str2double( regexp(f,'(?<=w=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            TA=str2double( regexp(f,'(?<=TA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            RA=str2double( regexp(f,'(?<=RA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            stop=str2double( regexp(f,'(?<=s=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            y=str2double( regexp(f,'(?<=y=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            m=str2double( regexp(f,'(?<=m=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            d=str2double( regexp(f,'(?<=d=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            hu=str2double( regexp(f,'(?<=h=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            mi=str2double( regexp(f,'(?<=mn=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            sec=str2double( regexp(f,'(?<=sec=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            t000=juliandate(y,m,d,hu,mi,sec);
            [R0, V0] = COE2RV(a,e,TA,RA,incl,w);
            X0=[R0';V0'];
            % E = norm(V0)^2/2 - 398600/norm(R0);
            n = sqrt(abs(398600/abs(a^3)));
            T = abs(2*pi/n);
            n_periods=stop;
            TAo = TA*pi/180;
            Eo = 2*atan(tan(TAo/2)*sqrt((1-e)/(1+e)));
            Mo = Eo - e*sin(Eo);
            to = Mo*(T/2/pi);
            tf = to + n_periods*T;
            % step=app.TimeStepDropDown.Value;
            % switch step
            %     case 'Seconds'
            %         tstep=1;
            %         ttt=1;xlab='Seconds';
            %     case 'Minutes'
            %         tstep=10;ttt=60;xlab='Minutes';
            %     case 'Hours'
            %         tstep=60*10;
            %         ttt=60*60;xlab='Hours';
            %     case 'Days'
            %         tstep=3600*10;
            %         ttt=60*60*24;xlab='Days';
            %     case 'years'
            %         tstep=3600*24*20;
            %         ttt=60*60*24*365;xlab='years';
            % end
            tstep=10;
            times = (to:tstep:tf);
            options = odeset('reltol', 1.e-8, ...
                'abstol', 1.e-8, ...
                'initialstep', T/10000, ...
                'events', @(t,y) terminate(t,y));

            %          for i=1:30
            %                values = [1:10:5000];
            coe0=ctokm(R0,V0);
            % RA0=coe0(3)*180/pi;
            % h0=coe0(1);
            % e0=coe0(2);
            % w0=coe0(5)*180/pi;
            % incl0=coe0(4)*180/pi;

            [tt, State] = ode45(@(t,y) dfdf(t,y,t000),times, X0, options);

            % anu=numel(tt)
            % anu2=numel(times)
            % anu2s=tstep
            mat=zeros(numel(tt),13);

            R=State(:,1:3);
            V=State(:,4:6);

            satelliteDP = app.satellite.DataProviders.Item('Cartesian Position').Group.Item('ICRF');
    results = satelliteDP.Exec(app.scenario.StartTime, app.scenario.StopTime, 10);


    % Extract the time and position data
    % timeArray = results.DataSets.GetDataSetByName('Time').GetValues;
    xArray = results.DataSets.GetDataSetByName('x').GetValues;
    yArray = results.DataSets.GetDataSetByName('y').GetValues;
    zArray = results.DataSets.GetDataSetByName('z').GetValues;
    velocityDP = app.satellite.DataProviders.Item('Cartesian Velocity').Group.Item('ICRF');
    velocityResults = velocityDP.Exec(app.scenario.StartTime, app.scenario.StopTime, 60);
    vxArray = velocityResults.DataSets.GetDataSetByName('x').GetValues;
    vyArray = velocityResults.DataSets.GetDataSetByName('y').GetValues;
    vzArray = velocityResults.DataSets.GetDataSetByName('z').GetValues;

    % Convert time to MATLAB serial date number
    % timeArray = datenum(timeArray);

    % Retrieve Keplerian elements
    keplerianDP = app.satellite.DataProviders.Item('Classical Elements').Group.Item('ICRF');
    resultsKeplerian = keplerianDP.Exec(app.scenario.StartTime, app.scenario.StopTime, 10);

    semiMajorAxisArray = resultsKeplerian.DataSets.GetDataSetByName('Semi-major Axis').GetValues;
    eccentricityArray = resultsKeplerian.DataSets.GetDataSetByName('Eccentricity').GetValues;
    inclinationArray = resultsKeplerian.DataSets.GetDataSetByName('Inclination').GetValues;
    raanArray = resultsKeplerian.DataSets.GetDataSetByName('RAAN').GetValues;
    argOfPeriapsisArray = resultsKeplerian.DataSets.GetDataSetByName('Arg of Perigee').GetValues;
    trueAnomalyArray = resultsKeplerian.DataSets.GetDataSetByName('True Anomaly').GetValues;

    % Convert ECI position to ECEF
    eciToEcefDP = app.satellite.DataProviders.Item('Cartesian Position').Group.Item('Fixed');
    resultsEcef = eciToEcefDP.Exec(app.scenario.StartTime, app.scenario.StopTime, 60);

    xEcefArray = resultsEcef.DataSets.GetDataSetByName('x').GetValues;
    yEcefArray = resultsEcef.DataSets.GetDataSetByName('y').GetValues;
    zEcefArray = resultsEcef.DataSets.GetDataSetByName('z').GetValues;
yax=app.yAxisDropDown.Value;
switch yax
    case 'Semi Majoraxis'
        y=cell2mat(semiMajorAxisArray);
        ylab='KM';
    case 'eccentricity'
        y=cell2mat(eccentricityArray);
        ylab='';
    case 'angulae momentum'
        y=1;
        ylab='KM^2/S';
    case 'True anomly'
        y=cell2mat(trueAnomalyArray);
        ylab='Deg';
    case 'Argument Of Perigee'
        y=cell2mat(argOfPeriapsisArray);
        ylab='Deg';
    case 'Rightessention'
        y=cell2mat(raanArray);
        ylab='Deg';
    case 'Inclination'
        ylab='Deg';
        y=cell2mat(inclinationArray);
    case 'X'
        % size(R)
        % size(cell2mat(xArray))
        st=cell2mat(xArray);
        y=st(1:end-1)-R(:,1);
        ylab='Km';
    case 'Y'
        st=cell2mat(yArray);
        y=st(1:end-1)-R(:,2);
        ylab='Km';
    case 'Z'
        st=cell2mat(zArray);
        y=st(1:end-1)-R(:,3);
        ylab='Km';
    case 'RMAG'
        y=vecnorm([cell2mat(xArray) cell2mat(yArray) cell2mat(zArray)]');
        ylab='Km';
    case 'Apogee'
        y=cell2mat(semiMajorAxisArray).*(1+cell2mat(eccentricityArray));
        ylab='Km';
    case 'Perigee'
        y=cell2mat(semiMajorAxisArray).*(1-cell2mat(eccentricityArray));
        ylab='Km';
    case 'VX'
        y=cell2mat(vxArray);
        ylab='Km/s';
    case 'VY'
        y=cell2mat(vyArray);
        ylab='Km/s';
    case 'VZ'
        y=cell2mat(vzArray);
        ylab='Km/s';
    case 'VMAG'
        y=vecnorm([cell2mat(vxArray) cell2mat(vyArray) cell2mat(vzArray)]');
        ylab='Km/s';


end
    
    % tt=0:10:app.dae;
    tt=linspace(0,app.dae,length(y));
    setFigureAlwaysOnTop(figure('Color','#C0C0C0','Toolbar','none','menubar','none','name',append(yax,' STK PLOT'),'NumberTitle','off'))
   size(tt)
   size(y)
    plot(tt,y());
    ylabel(ylab);
    % Compile data into a table for display
    % data = table(timeArray, semiMajorAxisArray, eccentricityArray, inclinationArray, ...
    %     raanArray, argOfPeriapsisArray, trueAnomalyArray, ...
    %     xArray, yArray, zArray, xEcefArray, yEcefArray, zEcefArray);

    % Display the first few rows of the data
    % disp(data(1:10, :));

    % Optionally, display the data in a table UI component in the app
    % app.UITable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.STKPlotUIFigure = uifigure('Visible', 'off');
            app.STKPlotUIFigure.Name = 'STK Plot';
            app.STKPlotUIFigure.Icon = fullfile(pathToMLAPP, 'Systems-Tool-Kit-STK.png');
            app.Panel = uipanel(app.STKPlotUIFigure);
            app.yAxisDropDownLabel = uilabel(app.Panel);
            app.yAxisDropDownLabel.Text = 'yAxis';
            app.yAxisDropDown = uidropdown(app.Panel);
            app.yAxisDropDown.Items = {'Semi Majoraxis', 'eccentricity', 'Perigee', 'Apogee', 'angulae momentum', 'Orbit Energy', 'True anomly', 'Mean anomly', 'Eccentric Anomaly', 'Argument Of Perigee', 'Rightessention', 'Inclination', 'X', 'Y', 'Z', 'VX', 'VY', 'VZ', 'RMAG', 'VMAG', 'Orbit Period'};
            app.yAxisDropDown.Value = 'Semi Majoraxis';
            app.plotButton = uibutton(app.Panel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Icon = 'rt_XYPlot.png';
            app.plotButton.Text = 'plot';
            app.RunSTKButton = uibutton(app.Panel, 'push');
            app.RunSTKButton.ButtonPushedFcn = createCallbackFcn(app, @RunSTKButtonPushed, true);
            app.RunSTKButton.Text = 'RunSTK';
            app.RunSTKButton.Icon = fullfile(pathToMLAPP, 'RunCalculation.png');
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'STKG.png';
            % Show the figure after all components are created
            app.STKPlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = STKplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.STKPlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.STKPlotUIFigure)
        end
    end
end