classdef app22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpaceCraftOrbitUIFigure       matlab.ui.Figure
        UTEditField                   matlab.ui.control.EditField
        UTEditFieldLabel              matlab.ui.control.Label
        EpochDatePicker               matlab.ui.control.DatePicker
        EpochDatePickerLabel          matlab.ui.control.Label
        StatetypeDropDown             matlab.ui.control.DropDown
        StatetypeDropDownLabel        matlab.ui.control.Label
        ElementsPanel                 matlab.ui.container.Panel
        POSITIONSPanel                matlab.ui.container.Panel
        StopEditField                 matlab.ui.control.NumericEditField
        StopEditFieldLabel            matlab.ui.control.Label
        R3XEditField                  matlab.ui.control.NumericEditField
        R3XEditFieldLabel             matlab.ui.control.Label
        R3YEditField                  matlab.ui.control.NumericEditField
        R3YEditFieldLabel             matlab.ui.control.Label
        R2XEditField                  matlab.ui.control.NumericEditField
        R2XEditFieldLabel             matlab.ui.control.Label
        R2YEditField                  matlab.ui.control.NumericEditField
        R2YEditFieldLabel             matlab.ui.control.Label
        R3ZEditField                  matlab.ui.control.NumericEditField
        R3ZEditFieldLabel             matlab.ui.control.Label
        R2ZEditField                  matlab.ui.control.NumericEditField
        R2ZEditFieldLabel             matlab.ui.control.Label
        R1ZEditField                  matlab.ui.control.NumericEditField
        R1ZEditFieldLabel             matlab.ui.control.Label
        R1YEditField                  matlab.ui.control.NumericEditField
        R1YEditFieldLabel             matlab.ui.control.Label
        R1XEditField                  matlab.ui.control.NumericEditField
        R1XEditFieldLabel             matlab.ui.control.Label
        STOPEditField                 matlab.ui.control.NumericEditField
        STOPEditFieldLabel            matlab.ui.control.Label
        TrueAnamolyEditField          matlab.ui.control.NumericEditField
        TrueAnamolyEditFieldLabel     matlab.ui.control.Label
        ArgumentOfPerigeeEditField    matlab.ui.control.NumericEditField
        ArgumentOfPerigeeEditFieldLabel  matlab.ui.control.Label
        RightessentionEditField       matlab.ui.control.NumericEditField
        RightessentionEditFieldLabel  matlab.ui.control.Label
        InclinationEditField          matlab.ui.control.NumericEditField
        InclinationEditFieldLabel     matlab.ui.control.Label
        EccentricityEditField         matlab.ui.control.NumericEditField
        EccentricityEditFieldLabel    matlab.ui.control.Label
        SemiMagorAxisEditField        matlab.ui.control.NumericEditField
        SemiMagorAxisEditFieldLabel   matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        ApplyButton                   matlab.ui.control.StateButton
        OKButton                      matlab.ui.control.Button
    end

    
    properties (Access = public)
        callingapp; % Description
        X; % Description
        f; % Description
        file;
        Property5 % Description
        value; % Description
    end
    
    methods (Access = public)
        
        function coe = CtoK(~,R,V)
            mu = 3.986012E5;
            eps = 1.e-10;
            r = norm(R);
            v = norm(V);
            vr = dot(R,V)/r;
            H = cross(R,V);
            h = norm(H);
            %...Equation 4.7:
            incl = acos(H(3)/h);
            %...Equation 4.8:
            N = cross([0 0 1],H);
            n = norm(N);
            %...Equation 4.9:
            if n ~= 0
                RA = acos(N(1)/n);
                if N(2) < 0
                    RA = 2*pi - RA;
                end
            else
                RA = 0;
            end
            %...Equation 4.10:
            E = 1/mu*((v^2 - mu/r)*R - r*vr*V);
            e = norm(E);
            %...Equation 4.12 (incorporating the case e = 0):
            if n ~= 0 
                if e > eps
                    w = acos(dot(N,E)/n/e);
                    if E(3) < 0
                        w = 2*pi - w;
                    end
                else
                   
                    w = 0;
                end
                
            else
                if e>eps
                    
                w =acos(E(1)/e);
                if E(2) < 0
                        w = 2*pi - w;
                end
                
                else
                    w=0;
                end
     
            end
            %...Equation 4.13a (incorporating the case e = 0):
            if e > eps
                
                TA = acos(dot(E,R)/e/r);
                
                if vr < 0
                    TA = 2*pi - TA;
                end
            else

                cp = cross(N,R);
                if cp(3) >= 0
                    TA = acos(dot(N,R)/n/r);
                else
                    TA = 2*pi - acos(dot(N,R)/n/r);
                end
                if n<=eps 
                    
                   TA=real(acos(R(1)/norm(R)));
                   if vr < 0
                    TA = 2*pi - TA;
                  end
                end
                
            end
            %...Equation 4.62 (a < 0 for a hyperbola):
            a=h^2/mu/(1 - e^2);
            coe = [h e RA incl w TA a];
        end %coe_from_sv
        
       
       function [R0, V0] = KtoC(~,a,e,TA,RA,incl,w)
            Pp=a*(1-e*e);
            mu = 3.986012E5;
            h=sqrt(Pp*mu);
            rp = (h^2/mu) * (1/(1 + e*cos(TA*pi/180))) * (cos(TA*pi/180)*[1;0;0] + sin(TA*pi/180)*[0;1;0]);
            vp = (mu/h) * (-sin(TA*pi/180)*[1;0;0] + (e + cos(TA*pi/180))*[0;1;0]);
            %% Rotation matrices
            R3_W = [ cos(RA*pi/180) sin(RA*pi/180) 0
                -sin(RA*pi/180) cos(RA*pi/180) 0
                0        0       1];
            R1_i = [1 0         0
                0 cos(incl*pi/180) sin(incl*pi/180)
                0 -sin(incl*pi/180) cos(incl*pi/180)];
            R3_w = [ cos(w*pi/180) sin(w*pi/180) 0
                -sin(w*pi/180) cos(w*pi/180) 0
                0       0      1];
            Q_pX = (R3_w*R1_i*R3_W)';
            r = Q_pX*rp;
            v = Q_pX*vp;
            r = r';
            v = v';
            V0=v';
            R0=r';
            X0=[R0;V0];
        end
        
        function [V2, ierr] = gibbs(~,R1, R2, R3)

            mu = 3.986012E5;
            tol = 1e-4;
            ierr = 0;

            r1 = norm(R1);
            r2 = norm(R2);
            r3 = norm(R3);
            c12 = cross(R1,R2);
            c23 = cross(R2,R3);
            c31 = cross(R3,R1);
            if abs(dot(R1,c23)/r1/norm(c23)) > tol
                ierr = 1;
            end

            N = r1*c23 + r2*c31 + r3*c12;

            D = c12 + c23 + c31;

            S = R1*(r2 - r3) + R2*(r3 - r1) + R3*(r1 - r2);

            V2 = sqrt(mu/norm(N)/norm(D))*(cross(D,R2)/r2 + S);

                end
        
                function [rmag,ra,dec,vmag,rav,decv] = C2SP(~,r,v)
                    % ––––––––––––––––––––––––––––––––––––––––––––––
                    
                    dec = atan2(r(3),sqrt(r(1)^2+r(2)^2))*180/pi;
                    ra=atan2(r(2),r(1))*180/pi;
                    decv=atan2(v(3),sqrt(v(1)^2+v(2)^2))*180/pi;
                    rav=atan2(v(2),v(1))*180/pi;
                    rmag=norm(r);
                    vmag=norm(v);
end
        
        function [R0,V0] =SP2C(~,rmag,ra,dec,vmag,rav,decv)
            R0=rmag*[(cosd(ra)*cosd(dec));(sind(ra)*cosd(dec));sind(dec)];
            V0=vmag*[(cosd(rav)*cosd(decv));(sind(rav)*cosd(decv));sind(decv)];
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%  app.X=0;
 app.SpaceCraftOrbitUIFigure.WindowStyle='alwaysontop';
app.file='Files\ali.orbit';
 app.f=fileread(app.file);
       
       app.value=1;
       a=str2double( regexp(app.f,'(?<=a=)([+-]?([0-9]*[.])?[0-9]+)','match'));
       e=str2double( regexp(app.f,'(?<=e=)([+-]?([0-9]*[.])?[0-9]+)','match'));
       incl=str2double( regexp(app.f,'(?<=i=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        w=str2double( regexp(app.f,'(?<=w=)([+-]?([0-9]*[.])?[0-9]+)','match'));
       TA=str2double( regexp(app.f,'(?<=TA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
       RA=str2double( regexp(app.f,'(?<=RA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
       stop=str2double( regexp(app.f,'(?<=s=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        y=str2double( regexp(app.f,'(?<=y=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        m=str2double( regexp(app.f,'(?<=m=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        d=str2double( regexp(app.f,'(?<=d=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        hu=str2double( regexp(app.f,'(?<=h=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        mi=str2double( regexp(app.f,'(?<=mn=)([+-]?([0-9]*[.])?[0-9]+)','match'));
        sec=str2double( regexp(app.f,'(?<=sec=)([+-]?([0-9]*[.])?[0-9]+)','match'));
%         app.UTEditField.Value=datetime(202app.EpochDatePicker.Value
app.EpochDatePicker.Value=datetime(y,m,d);
        app.UTEditField.Value=append(num2str(hu),':',num2str(mi),':',num2str(sec));
        
       app.SemiMagorAxisEditField.Value=a;
       app.EccentricityEditField.Value=e;
       app.InclinationEditField.Value=incl;
       app.RightessentionEditField.Value=RA;
        app.ArgumentOfPerigeeEditField.Value=w;
       app.TrueAnamolyEditField.Value=TA;
       
       app.STOPEditField.Value=stop;
       
%             app.TextArea.Value=f;
            %           app.callingapp=app21;
%                 app.TextArea2.Value=DataForapp21;
% app.X=app21;
% app.X.AliOrbitUIFigure.Visible=0;
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
%              importdata(app21,app.TextArea2.Value)
%              delete(app)
%       app.Label.Text=app.SemiMajorAxisEditField.Value 
%            [fileID,path]=uigetfile('*.orbit');
%            app.x=1;
          app.ApplyButtonValueChanged
%             file='C:\Users\Ali\Desktop\ali.orbit'
%             a=app.SemiMagorAxisEditField.Value;
%             e=app.EccentricityEditField.Value;
%             i=app.InclinationEditField.Value;
%             RA=app.RightessentionEditField.Value;
%             w=app.ArgumentOfPerigeeEditField.Value;
%             TA=app.TrueAnamolyEditField.Value;
%             stop=app.STOPEditField.Value;
%             fileID1 = fopen(file,'w');
%             
%             fprintf(fileID1,'t=%f\n',1);
%             fprintf(fileID1,'a=%f\n',a);
%             fprintf(fileID1,'e=%f\n',e);
%             fprintf(fileID1,'i=%f\n',i);
%             fprintf(fileID1,'TA=%f\n',TA);
%             fprintf(fileID1,'RA=%f\n',RA);
%             fprintf(fileID1,'w=%f\n',w);
%             fprintf(fileID1,'s=%f\n',stop);
             close(app.SpaceCraftOrbitUIFigure)


%             save('exp.txt');
%                fclose(file);

        end

        % Close request function: SpaceCraftOrbitUIFigure
        function SpaceCraftOrbitUIFigureCloseRequest(app, event)
            delete(app.SpaceCraftOrbitUIFigure)
           
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            close(app.SpaceCraftOrbitUIFigure)
                       
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
%          
% app.x=1;            
         file1='Files\ali.orbit';
         fileID1 = fopen(file1,'w');
           value=app.EpochDatePicker.Value;
           t = datetime(value);
           v = datevec(t);
           y=v(1);
           m=v(2);
           d= v(3);
           t2=datetime(app.UTEditField.Value);
           v2=datevec(t2);
           h=v2(4);
           mi=v2(5);
           se=v2(6);
           fprintf(fileID1,'y=%f\n',y);
           fprintf(fileID1,'m=%f\n',m);
           fprintf(fileID1,'d=%f\n',d);
           fprintf(fileID1,'h=%f\n',h);
           fprintf(fileID1,'mn=%f\n',mi);
           fprintf(fileID1,'sec=%f\n',se);
%            to=juliandate(y,m,d,h,mi,se)
            value = app.StatetypeDropDown.Value;
            a=app.SemiMagorAxisEditField.Value;
            e=app.EccentricityEditField.Value;
            i=app.InclinationEditField.Value;
            RA=app.RightessentionEditField.Value;
            w=app.ArgumentOfPerigeeEditField.Value;
            TA=app.TrueAnamolyEditField.Value;
            stop=app.STOPEditField.Value;
           
            switch(value)
                case'Keplerian'
                    eps = 1.e-10;
                    if(e<eps)
                        if(i~=0)
                            TA=TA+w;
                            w=0;
                        else
                            TA=TA+w+RA;
                            w=0;
                            RA=0;
                        end
                    end
                    if(i==0)
                        if(e>eps)
                            w=w+RA;
                            RA=0;
                        end
                    end
%              fprintf(fileID1,'T=%f\n',1);
            fprintf(fileID1,'a=%f\n',a);
            fprintf(fileID1,'e=%f\n',e);
            fprintf(fileID1,'i=%f\n',i);
            fprintf(fileID1,'TA=%f\n',TA);
            fprintf(fileID1,'RA=%f\n',RA);
            fprintf(fileID1,'w=%f\n',w);
            fprintf(fileID1,'s=%f\n',stop);
             case'Cartisian'
                     R0=[a e i]';
                    V0=[RA w TA]';
                    coe=CtoK(app,R0,V0);
                    e = coe(2);
                    RA = coe(3)*180/pi;
                    incl = coe(4)*180/pi;
                    w = coe(5)*180/pi;
                    TA = coe(6)*180/pi;
                    a = coe(7);
%             fprintf(fileID1,'T=%f\n',0);
            fprintf(fileID1,'a=%f\n',a);
            fprintf(fileID1,'e=%f\n',e);
            fprintf(fileID1,'i=%f\n',incl);
            fprintf(fileID1,'TA=%f\n',TA);
            fprintf(fileID1,'RA=%f\n',RA);
            fprintf(fileID1,'w=%f\n',w);
            fprintf(fileID1,'s=%f\n',stop);
            case'3 Position Vector'
%                     app.ElementsPanel.Visible=0;
                    app.POSITIONSPanel.Visible=1;
                    R1X=app.R1XEditField.Value;
                    R1Y=app.R1YEditField.Value;
                    R1Z=app.R1ZEditField.Value;
                    R2X=app.R2XEditField.Value;
                    R2Y=app.R2YEditField.Value;
                    R2Z=app.R2ZEditField.Value;
                    R3X=app.R3XEditField.Value;
                    R3Y=app.R3YEditField.Value;
                    R3Z=app.R3ZEditField.Value;
                    S=app.StopEditField.Value;
                    R1=[R1X R1Y R1Z];
                    R2=[R2X R2Y R2Z];
                    R3=[R3X R3Y R3Z];
                    [V2, ierr] = gibbs(app,R1, R2, R3);
                    if ierr == 1
                        msgbox('These vectors are not coplanar');
                        return
                    end

                     coe=CtoK(app,R2,V2);
                    e = coe(2);
                    RA = coe(3)*180/pi;
                    incl = coe(4)*180/pi;
                    w = coe(5)*180/pi;
                    TA = coe(6)*180/pi;
                    a = coe(7);
%             fprintf(fileID1,'T=%f\n',0);
            fprintf(fileID1,'a=%f\n',a);
            fprintf(fileID1,'e=%f\n',e);
            fprintf(fileID1,'i=%f\n',incl);
            fprintf(fileID1,'TA=%f\n',TA);
            fprintf(fileID1,'RA=%f\n',RA);
            fprintf(fileID1,'w=%f\n',w);
            fprintf(fileID1,'s=%f\n',S);
                case'Spherical'
                    [R0,V0]=SP2C(app,a,e,i,RA,w,TA);
                   coe=CtoK(app,R0,V0);
                    e = coe(2);
                    RA = coe(3)*180/pi;
                    incl = coe(4)*180/pi;
                    w = coe(5)*180/pi;
                    TA = coe(6)*180/pi;
                    a = coe(7);
%             fprintf(fileID1,'T=%f\n',0);
            fprintf(fileID1,'a=%f\n',a);
            fprintf(fileID1,'e=%f\n',e);
            fprintf(fileID1,'i=%f\n',incl);
            fprintf(fileID1,'TA=%f\n',TA);
            fprintf(fileID1,'RA=%f\n',RA);
            fprintf(fileID1,'w=%f\n',w);
            fprintf(fileID1,'s=%f\n',stop); 
            end
            
%             app.X.Label.Text= num2str(app.NameEditField.Value);
            
        end

        % Value changed function: SemiMagorAxisEditField
        function SemiMagorAxisEditFieldValueChanged(app, event)
            value = app.SemiMagorAxisEditField.Value;
            
        end

        % Value changed function: EccentricityEditField
        function EccentricityEditFieldValueChanged(app, event)
            value = app.EccentricityEditField.Value;
            
        end

        % Value changed function: InclinationEditField
        function InclinationEditFieldValueChanged(app, event)
            value = app.InclinationEditField.Value;
            
        end

        % Value changed function: ArgumentOfPerigeeEditField
        function ArgumentOfPerigeeEditFieldValueChanged(app, event)
            value = app.ArgumentOfPerigeeEditField.Value;
            
            
        end

        % Value changed function: RightessentionEditField
        function RightessentionEditFieldValueChanged(app, event)
            value = app.RightessentionEditField.Value;
            
        end

        % Value changed function: StatetypeDropDown
        function StatetypeDropDownValueChanged(app, event)
            value = app.StatetypeDropDown.Value;
                x=0;
            switch(value)
              
                case'Cartisian'
                    app.POSITIONSPanel.Visible=0;
                     app.ElementsPanel.Visible=1;
                     app.SemiMagorAxisEditFieldLabel.Text='  X           ';
                    app.EccentricityEditFieldLabel.Text='Y     ';
                    app.InclinationEditFieldLabel. Text=' Z    '; 
                    app.RightessentionEditFieldLabel.Text=' VX            ';
                    app.ArgumentOfPerigeeEditFieldLabel.Text=' VY                    ';
                    app.TrueAnamolyEditFieldLabel.Text=' VZ          ';
                    
                    a=app.SemiMagorAxisEditField.Value;
                    e=app.EccentricityEditField.Value;
                    incl=app.InclinationEditField.Value;
                    RA=app.RightessentionEditField.Value;
                    w=app.ArgumentOfPerigeeEditField.Value;
                    TA=app.TrueAnamolyEditField.Value;
                     if(app.value==1)
                     
                    eps = 1.e-10;
                    if(e<eps)
                        if(i~=0)
                            TA=TA+w;
                            w=0;
                        else
                            TA=TA+w+RA;
                            w=0;
                            RA=0;
                        end
                    end
                    if(i==0)
                        if(e>eps)
                            w=w+RA;
                            RA=0;
                        end
                    end
                    [R0,V0]=KtoC(app,a,e,TA,RA,incl,w);
                     elseif(app.value==3)
                         [R0,V0]=SP2C(app,a,e,incl,RA,w,TA);
                    
                     end
                     app.SemiMagorAxisEditField.Value=R0(1);
                    app.EccentricityEditField.Value=R0(2);
                    app.InclinationEditField.Value=R0(3);
                    app.RightessentionEditField.Value=V0(1);
                    app.ArgumentOfPerigeeEditField.Value=V0(2);
                    app.TrueAnamolyEditField.Value=V0(3);
                    app.value=2;
                case'Keplerian'
                    app.ElementsPanel.Visible=1;
                     app.POSITIONSPanel.Visible=0;
                    X1=app.SemiMagorAxisEditField.Value;
                    Y=app.EccentricityEditField.Value;
                    Z=app.InclinationEditField.Value;
                    VX=app.RightessentionEditField.Value;
                    VY=app.ArgumentOfPerigeeEditField.Value;
                    VZ=app.TrueAnamolyEditField.Value;
                    app.SemiMagorAxisEditFieldLabel.Text='SemiMagorAxis';
                    app.EccentricityEditFieldLabel.Text='Eccentricity';
                    app.InclinationEditFieldLabel.Text='Inclination';
                    app.RightessentionEditFieldLabel.Text='Rightessention';
                    app.ArgumentOfPerigeeEditFieldLabel.Text='ArgumentOfPerigee';
                    app.TrueAnamolyEditFieldLabel.Text='TrueAnamoly';
                    if(app.value==2)
                        
                    R0=[X1 Y Z]';
                    V0=[VX VY VZ]';
                    
                    elseif(app.value==3)
                        [R0,V0]=SP2C(app,X1,Y,Z,VX,VY,VZ);
                    
                    end
                    coe=CtoK(app,R0,V0);
                    e = coe(2);
                    RA = coe(3)*180/pi;
                    incl = coe(4)*180/pi;
                    w = coe(5)*180/pi;
                    TA = coe(6)*180/pi;
                    a = coe(7);
                    app.SemiMagorAxisEditField.Value=a;
                    app.EccentricityEditField.Value=e;
                    app.InclinationEditField.Value=incl;
                    app.RightessentionEditField.Value=RA;
                    app.ArgumentOfPerigeeEditField.Value=w;
                    app.TrueAnamolyEditField.Value=real(TA);
                    app.value=1;
                case'3 Position Vector'
%                     app.ElementsPanel.Visible=0;
                    app.POSITIONSPanel.Visible=1;
%                     R1X=app.R1XEditField.Value;
%                     R1Y=app.R1YEditField.Value;
%                     R1Z=app.R1ZEditField.Value;
%                     R2X=app.R2XEditField.Value;
%                     R2Y=app.R2YEditField.Value;
%                     R2Z=app.R2ZEditField.Value;
%                     R3X=app.R3XEditField.Value;
%                     R3Y=app.R3YEditField.Value;
%                     R3Z=app.R3ZEditField.Value;
%                     R1=[R1X R1Y R1Z];
%                     R2=[R2X R2Y R2Z];
%                     R1=[R3X R3Y R3Z];
%                     [v2, ierr] = gibbs(app,R1, R2, R3);


%                     
                case'Spherical'
                    app.SemiMagorAxisEditFieldLabel.Text='  RMAG           ';
                    app.EccentricityEditFieldLabel.Text='RA          ';
                    app.InclinationEditFieldLabel. Text=' DEC      '; 
                    app.RightessentionEditFieldLabel.Text=' VMAG            ';
                    app.ArgumentOfPerigeeEditFieldLabel.Text=' RAV                      ';
                    app.TrueAnamolyEditFieldLabel.Text=' DECV            ';
                         X1=app.SemiMagorAxisEditField.Value;
                        Y=app.EccentricityEditField.Value;
                        Z=app.InclinationEditField.Value;
                        VX=app.RightessentionEditField.Value;
                        VY=app.ArgumentOfPerigeeEditField.Value;
                        VZ=app.TrueAnamolyEditField.Value;
                    if(app.value==2)
                       R0=[X1 Y Z]';
                       V0=[VX VY VZ]';
                    elseif(app.value==1)
%                         KtoC(app,a,e,TA,RA,incl,w);
                       [R0,V0]=KtoC(app,X1,Y,VZ,VX,Z,VY) ;
                       
                        
                    end
                    [rmag,ra,dec,vmag,rav,decv] = C2SP(app,R0,V0);
                    app.SemiMagorAxisEditField.Value=rmag;
                    app.EccentricityEditField.Value=ra;
                    app.InclinationEditField.Value=dec;
                    app.RightessentionEditField.Value=vmag;
                    app.ArgumentOfPerigeeEditField.Value=rav;
                    app.TrueAnamolyEditField.Value=decv;
                    app.value=3;
                    
            end
            
        end

        % Value changed function: TrueAnamolyEditField
        function TrueAnamolyEditFieldValueChanged(app, event)
            value = app.TrueAnamolyEditField.Value;
            
        end

        % Drop down opening function: StatetypeDropDown
        function StatetypeDropDownOpening(app, event)
            
        end

        % Value changed function: EpochDatePicker
        function EpochDatePickerValueChanged(app, event)
            value = app.EpochDatePicker.Value;
            
        end

        % Value changed function: UTEditField
        function UTEditFieldValueChanged(app, event)
            value = app.UTEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpaceCraftOrbitUIFigure = uifigure('Visible', 'off');
            app.SpaceCraftOrbitUIFigure.Name = ' SpaceCraft Orbit';
            app.SpaceCraftOrbitUIFigure.Icon = 'logo.png';
            app.SpaceCraftOrbitUIFigure.CloseRequestFcn = createCallbackFcn(app, @SpaceCraftOrbitUIFigureCloseRequest, true);
            app.OKButton = uibutton(app.SpaceCraftOrbitUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.ApplyButton = uibutton(app.SpaceCraftOrbitUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.SpaceCraftOrbitUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ElementsPanel = uipanel(app.SpaceCraftOrbitUIFigure);
            app.ElementsPanel.Title = 'Elements';
            app.SemiMagorAxisEditFieldLabel = uilabel(app.ElementsPanel);
            app.SemiMagorAxisEditFieldLabel.Text = {'SemiMagorAxis'; ''};
            app.SemiMagorAxisEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.SemiMagorAxisEditField.ValueChangedFcn = createCallbackFcn(app, @SemiMagorAxisEditFieldValueChanged, true);
            app.SemiMagorAxisEditField.Value = 6478;
            app.EccentricityEditFieldLabel = uilabel(app.ElementsPanel);
            app.EccentricityEditFieldLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.EccentricityEditField.Limits = [0.0001 3];
            app.EccentricityEditField.ValueChangedFcn = createCallbackFcn(app, @EccentricityEditFieldValueChanged, true);
            app.EccentricityEditField.Value = 0.0001;
            app.InclinationEditFieldLabel = uilabel(app.ElementsPanel);
            app.InclinationEditFieldLabel.Text = 'Inclination';
            app.InclinationEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.InclinationEditField.ValueChangedFcn = createCallbackFcn(app, @InclinationEditFieldValueChanged, true);
            app.RightessentionEditFieldLabel = uilabel(app.ElementsPanel);
            app.RightessentionEditFieldLabel.Text = 'Rightessention';
            app.RightessentionEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.RightessentionEditField.ValueChangedFcn = createCallbackFcn(app, @RightessentionEditFieldValueChanged, true);
            app.RightessentionEditField.Tooltip = {''};
            app.ArgumentOfPerigeeEditFieldLabel = uilabel(app.ElementsPanel);
            app.ArgumentOfPerigeeEditFieldLabel.Text = 'ArgumentOfPerigee';
            app.ArgumentOfPerigeeEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.ArgumentOfPerigeeEditField.ValueChangedFcn = createCallbackFcn(app, @ArgumentOfPerigeeEditFieldValueChanged, true);
            app.TrueAnamolyEditFieldLabel = uilabel(app.ElementsPanel);
            app.TrueAnamolyEditFieldLabel.Text = 'TrueAnamoly';
            app.TrueAnamolyEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.TrueAnamolyEditField.ValueChangedFcn = createCallbackFcn(app, @TrueAnamolyEditFieldValueChanged, true);
            app.STOPEditFieldLabel = uilabel(app.ElementsPanel);
            app.STOPEditFieldLabel.Text = 'STOP ';
            app.STOPEditField = uieditfield(app.ElementsPanel, 'numeric');
            app.STOPEditField.Limits = [1 Inf];
            app.STOPEditField.Value = 1;
            app.POSITIONSPanel = uipanel(app.ElementsPanel);
            app.POSITIONSPanel.Title = 'POSITIONS';
            app.POSITIONSPanel.Visible = 'off';
            app.R1XEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R1XEditFieldLabel.Text = 'R1X';
            app.R1XEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R1YEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R1YEditFieldLabel.Text = 'R1Y';
            app.R1YEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R1ZEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R1ZEditFieldLabel.Text = 'R1Z';
            app.R1ZEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R2ZEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R2ZEditFieldLabel.Text = 'R2Z';
            app.R2ZEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R3ZEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R3ZEditFieldLabel.Text = 'R3Z';
            app.R3ZEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R2YEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R2YEditFieldLabel.Text = 'R2Y';
            app.R2YEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R2XEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R2XEditFieldLabel.Text = 'R2X';
            app.R2XEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R3YEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R3YEditFieldLabel.Text = 'R3Y';
            app.R3YEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.R3XEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.R3XEditFieldLabel.Text = 'R3X';
            app.R3XEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.StopEditFieldLabel = uilabel(app.POSITIONSPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.POSITIONSPanel, 'numeric');
            app.StopEditField.Value = 1;
            app.StatetypeDropDownLabel = uilabel(app.SpaceCraftOrbitUIFigure);
            app.StatetypeDropDownLabel.Text = 'State type';
            app.StatetypeDropDown = uidropdown(app.SpaceCraftOrbitUIFigure);
            app.StatetypeDropDown.Items = {'Cartisian', 'Keplerian', '3 Position Vector', 'Spherical'};
            app.StatetypeDropDown.DropDownOpeningFcn = createCallbackFcn(app, @StatetypeDropDownOpening, true);
            app.StatetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @StatetypeDropDownValueChanged, true);
            app.StatetypeDropDown.Value = 'Keplerian';
            app.EpochDatePickerLabel = uilabel(app.SpaceCraftOrbitUIFigure);
            app.EpochDatePickerLabel.Text = 'Epoch';
            app.EpochDatePicker = uidatepicker(app.SpaceCraftOrbitUIFigure);
            app.EpochDatePicker.ValueChangedFcn = createCallbackFcn(app, @EpochDatePickerValueChanged, true);
            app.EpochDatePicker.Value = datetime([2022 8 8]);
            app.UTEditFieldLabel = uilabel(app.SpaceCraftOrbitUIFigure);
            app.UTEditFieldLabel.Text = 'UT';
            app.UTEditField = uieditfield(app.SpaceCraftOrbitUIFigure, 'text');
            app.UTEditField.ValueChangedFcn = createCallbackFcn(app, @UTEditFieldValueChanged, true);
            app.UTEditField.Value = '11:59:59';
            % Show the figure after all components are created
            app.SpaceCraftOrbitUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpaceCraftOrbitUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpaceCraftOrbitUIFigure)
        end
    end
end