classdef perdict < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        passTimeUIFigure         matlab.ui.Figure
        LocationPanel            matlab.ui.container.Panel
        AltitudeEditFieldLabel   matlab.ui.control.Label
        AltitudeEditField        matlab.ui.control.NumericEditField
        longitudeEditFieldLabel  matlab.ui.control.Label
        longitudeEditField       matlab.ui.control.NumericEditField
        latitudeEditFieldLabel   matlab.ui.control.Label
        latitudeEditField        matlab.ui.control.NumericEditField
        predictpassTimeLabel     matlab.ui.control.Label
        OKButton                 matlab.ui.control.Button
        ApplyButton              matlab.ui.control.StateButton
        CancelButton             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.passTimeUIFigure.WindowStyle='alwaysontop';
        end

        % Value changed function: ApplyButton
        function ApplyButtonValueChanged(app, event)
            value = app.ApplyButton.Value;
            file1='Files\pass.orbit';
               fileID1 = fopen(file1,'w');
               lat=app.latitudeEditField.Value;
               long=app.longitudeEditField.Value;
               fprintf(fileID1,'lat=%f\n',lat);
               fprintf(fileID1,'long=%f\n',long);
        end

        % Value changed function: latitudeEditField
        function latitudeEditFieldValueChanged(app, event)
            value = app.latitudeEditField.Value;
            
        end

        % Value changed function: longitudeEditField
        function longitudeEditFieldValueChanged(app, event)
            value = app.longitudeEditField.Value;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.ApplyButtonValueChanged;
            close(app.passTimeUIFigure);
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
                        close(app.passTimeUIFigure);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.passTimeUIFigure = uifigure('Visible', 'off');
            app.passTimeUIFigure.Name = 'pass Time ';
            app.passTimeUIFigure.Icon = 'logo.png';
            app.CancelButton = uibutton(app.passTimeUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ApplyButton = uibutton(app.passTimeUIFigure, 'state');
            app.ApplyButton.ValueChangedFcn = createCallbackFcn(app, @ApplyButtonValueChanged, true);
            app.ApplyButton.Text = 'Apply';
            app.OKButton = uibutton(app.passTimeUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.predictpassTimeLabel = uilabel(app.passTimeUIFigure);
            app.predictpassTimeLabel.Text = 'predict pass Time';
            app.LocationPanel = uipanel(app.passTimeUIFigure);
            app.LocationPanel.Title = 'Location';
            app.latitudeEditField = uieditfield(app.LocationPanel, 'numeric');
            app.latitudeEditField.Limits = [-90 90];
            app.latitudeEditField.ValueChangedFcn = createCallbackFcn(app, @latitudeEditFieldValueChanged, true);
            app.latitudeEditField.Value = 30;
            app.latitudeEditFieldLabel = uilabel(app.LocationPanel);
            app.latitudeEditFieldLabel.Text = 'latitude';
            app.longitudeEditField = uieditfield(app.LocationPanel, 'numeric');
            app.longitudeEditField.Limits = [-180 180];
            app.longitudeEditField.ValueChangedFcn = createCallbackFcn(app, @longitudeEditFieldValueChanged, true);
            app.longitudeEditField.Value = 30;
            app.longitudeEditFieldLabel = uilabel(app.LocationPanel);
            app.longitudeEditFieldLabel.Text = 'longitude';
            app.AltitudeEditField = uieditfield(app.LocationPanel, 'numeric');
            app.AltitudeEditField.Limits = [0 Inf];
            app.AltitudeEditFieldLabel = uilabel(app.LocationPanel);
            app.AltitudeEditFieldLabel.Text = 'Altitude';
            % Show the figure after all components are created
            app.passTimeUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perdict

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.passTimeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.passTimeUIFigure)
        end
    end
end