classdef xyplot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotUIFigure           matlab.ui.Figure
        Panel                  matlab.ui.container.Panel
        PlotallButton          matlab.ui.control.Button
        VariationsSwitch       matlab.ui.control.Switch
        VariationsSwitchLabel  matlab.ui.control.Label
        plotButton             matlab.ui.control.Button
        TimeStepDropDown       matlab.ui.control.DropDown
        TimeStepDropDownLabel  matlab.ui.control.Label
        yAxisDropDown          matlab.ui.control.DropDown
        yAxisDropDownLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PlotUIFigure.WindowStyle='alwaysontop';
        end

        % Button pushed function: plotButton
        function plotButtonPushed(app, event)
            % hold off
            file='Files\ali.orbit';
            %             file1='C:\Users\Ali\Desktop\pass.orbit';
            f=fileread(file);
            a=str2double( regexp(f,'(?<=a=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            e=str2double( regexp(f,'(?<=e=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            incl=str2double( regexp(f,'(?<=i=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            w=str2double( regexp(f,'(?<=w=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            TA=str2double( regexp(f,'(?<=TA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            RA=str2double( regexp(f,'(?<=RA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            stop=str2double( regexp(f,'(?<=s=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            y=str2double( regexp(f,'(?<=y=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            m=str2double( regexp(f,'(?<=m=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            d=str2double( regexp(f,'(?<=d=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            hu=str2double( regexp(f,'(?<=h=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            mi=str2double( regexp(f,'(?<=mn=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            sec=str2double( regexp(f,'(?<=sec=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            t000=juliandate(y,m,d,hu,mi,sec);
            [R0, V0] = COE2RV(a,e,TA,RA,incl,w);
            X0=[R0';V0'];
            % E = norm(V0)^2/2 - 398600/norm(R0);
            n = sqrt(abs(398600/abs(a^3)));
            T = abs(2*pi/n);
            n_periods=stop;
            TAo = TA*pi/180;
            Eo = 2*atan(tan(TAo/2)*sqrt((1-e)/(1+e)));
            Mo = Eo - e*sin(Eo);
            to = Mo*(T/2/pi);
            tf = to + n_periods*T;
            step=app.TimeStepDropDown.Value;
            switch step
                case 'Seconds'
                    tstep=1;
                    ttt=1;xlab='Seconds';
                case 'Minutes'
                    tstep=10;ttt=60;xlab='Minutes';
                case 'Hours'
                    tstep=60*10;
                    ttt=60*60;xlab='Hours';
                case 'Days'
                    tstep=3600*10;
                    ttt=60*60*24;xlab='Days';
                case 'years'
                    tstep=3600*24*20;
                    ttt=60*60*24*365;xlab='years';
            end
            times = (to:tstep:tf);
            options = odeset('reltol', 1.e-8, ...
                'abstol', 1.e-8, ...
                'initialstep', T/10000, ...
                'events', @(t,y) terminate(t,y));

            %          for i=1:30
            %                values = [1:10:5000];
            coe0=ctokm(R0,V0);
            % RA0=coe0(3)*180/pi;
            % h0=coe0(1);
            % e0=coe0(2);
            % w0=coe0(5)*180/pi;
            % incl0=coe0(4)*180/pi;

            [tt, State] = ode45(@(t,y) dfdf(t,y,t000),times, X0, options);

            % anu=numel(tt)
            % anu2=numel(times)
            % anu2s=tstep
            mat=zeros(numel(tt),13);

            R=State(:,1:3);
            V=State(:,4:6);

            for i=1:numel(tt)
                mat(i,1:13)=ctokm(R(i,1:3),V(i,1:3));
            end
            %            tic
            %           mat=manyctok(R,V);
            %           toc
            yax=app.yAxisDropDown.Value;
            switch yax
                case 'Semi Majoraxis'
                    y=7;
                    ylab='Km';
                case 'eccentricity'
                    y=2;
                    ylab='';
                case 'angulae momentum'
                    y=1;
                    ylab='Km^2/s';
                case 'True anomly'
                    y=6;
                    ylab='Deg';
                case 'Argument Of Perigee'
                    y=5;
                    ylab='Deg';
                case 'Rightessention'
                    y=3;
                    ylab='Deg';
                case 'Inclination'
                    ylab='Deg';
                    y=4;
                case 'Orbit Period'
                    y=8;
                    ylab='seconds';
                case 'Orbit Energy'
                    y=9; ylab='Ks^2/s^2';
                case 'Perigee'
                    y=10;
                    ylab='KM';
                case 'Apogee'
                    y=11;
                    ylab='KM';
                case 'Mean anomly'
                    y=12;
                    ylab='Deg';
                case 'Eccentric Anomaly'
                    y=13;
                    ylab='Deg';
                case 'X'
                    y1=R(:,1);
                    ylab='km';
                case 'Y'
                    y1=R(:,2);
                    ylab='km';
                case 'Z'
                    y1=R(:,3);
                    ylab='km';
                case 'RMAG'
                    y1=vecnorm(R');
                    ylab='km';
                case 'VX'
                    y1=V(:,1);
                    ylab='km/s';
                case 'VY'
                    y1=V(:,2);
                    ylab='km/s';
                case 'VZ'
                    y1=V(:,3);
                    ylab='km/s';
                case 'VMAG'
                    y1=vecnorm(V');
                    ylab='km/s';


            end
            setFigureAlwaysOnTop(figure('Color','#C0C0C0','Toolbar','none','menubar','none','name',append(yax,' MATLAB_PLOT'),'NumberTitle','off'))
            try
                if(app.VariationsSwitch.Value=='1')
                    plot(tt/(ttt),(mat(:,y)-coe0(y)));
                else
                    plot(tt/(ttt),(mat(:,y)));
                end

            catch
                plot(tt/(ttt),y1);
            end
            xlabel(xlab)
            ylabel(ylab)
            hold on
            xlim([0 max(tt/(ttt))])
            grid on
            set(gca,'Color','#E0E0E0','GridColor','k','GridAlpha',0.5)
        end

        % Value changed function: VariationsSwitch
        function VariationsSwitchValueChanged(app, event)
            % value = app.VariationsSwitch.Value;

        end

        % Value changed function: yAxisDropDown
        function yAxisDropDownValueChanged(app, event)
            % value = app.yAxisDropDown.Value;

        end

        % Button pushed function: PlotallButton
        function PlotallButtonPushed(app, event)
            file='ali.orbit';
            %             file1='C:\Users\Ali\Desktop\pass.orbit';
            f=fileread(file);
            a=str2double( regexp(f,'(?<=a=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            e=str2double( regexp(f,'(?<=e=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            incl=str2double( regexp(f,'(?<=i=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            w=str2double( regexp(f,'(?<=w=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            TA=str2double( regexp(f,'(?<=TA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            RA=str2double( regexp(f,'(?<=RA=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            stop=str2double( regexp(f,'(?<=s=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            y=str2double( regexp(f,'(?<=y=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            m=str2double( regexp(f,'(?<=m=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            d=str2double( regexp(f,'(?<=d=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            hu=str2double( regexp(f,'(?<=h=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            mi=str2double( regexp(f,'(?<=mn=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            sec=str2double( regexp(f,'(?<=sec=)([+-]?([0-9]*[.])?[0-9]+)','match'));
            t000=juliandate(y,m,d,hu,mi,sec);
            [R0, V0] = COE2RV(a,e,TA,RA,incl,w);
            X0=[R0';V0'];
            % E = norm(V0)^2/2 - 398600/norm(R0);
            n = sqrt(abs(398600/abs(a^3)));
            T = abs(2*pi/n);
            n_periods=stop;
            TAo = TA*pi/180;
            Eo = 2*atan(tan(TAo/2)*sqrt((1-e)/(1+e)));
            Mo = Eo - e*sin(Eo);
            to = Mo*(T/2/pi);
            tf = to + n_periods*T;
            step=app.TimeStepDropDown.Value;
            switch step
                case 'Seconds'
                    tstep=1;
                    ttt=1;xlab='Seconds';
                case 'Minutes'
                    tstep=30;ttt=60;xlab='Minutes';
                case 'Hours'
                    tstep=60*10;
                    ttt=60*60;xlab='Hours';
                case 'Days'
                    tstep=3600*10;
                    ttt=60*60*24;xlab='Days';
                case 'years'
                    tstep=3600*24*100;
                    ttt=60*60*24*365;xlab='years';
            end
            times = (to:tstep:tf);
            options = odeset('reltol', 1.e-8, ...
                'abstol', 1.e-8, ...
                'events', @(t,y) terminate(t,y));

            %          for i=1:30
            %                values = [1:10:5000];
            coe0=ctokm(R0,V0);
            % RA0=coe0(3)*180/pi;
            % h0=coe0(1);
            % e0=coe0(2);
            % w0=coe0(5)*180/pi;
            % incl0=coe0(4)*180/pi;

            [tt, State] = ode45(@(t,y) dfdf(t,y,t000),times, X0, options);
            mat=zeros(numel(tt),13);
            R=State(:,1:3);
            V=State(:,4:6);
            for i=1:numel(tt)
                mat(i,1:13)=ctokm(R(i,1:3),V(i,1:3));
            end
            labels=["SPANDMO","ecc","RA","inacl","AOP","TA","SMA","period","Energy","praps","apoaps","MA","EA"];
            for i=1:13
                setFigureAlwaysOnTop(figure('Color','#C0C0C0','Toolbar','none','menubar','none','name',append('Delta ',labels(i)),'NumberTitle','off'))
                if(app.VariationsSwitch.Value=='1')
                    plot(tt/(ttt),(mat(:,i)-coe0(i)));
                else
                    plot(tt/(ttt),(mat(:,i)));
                end
                xlabel(xlab)
                %           ylabel(ylab)
                grid on
                set(gca,'Color','#E0E0E0','GridColor','k','GridAlpha',0.5)
                pause(.1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotUIFigure = uifigure('Visible', 'off');
            app.PlotUIFigure.Name = 'Plot';
            app.PlotUIFigure.Icon = 'rt_XYPlot.png';
            app.Panel = uipanel(app.PlotUIFigure);
            app.yAxisDropDownLabel = uilabel(app.Panel);
            app.yAxisDropDownLabel.Text = 'yAxis';
            app.yAxisDropDown = uidropdown(app.Panel);
            app.yAxisDropDown.Items = {'Semi Majoraxis', 'eccentricity', 'Perigee', 'Apogee', 'angulae momentum', 'Orbit Energy', 'True anomly', 'Mean anomly', 'Eccentric Anomaly', 'Argument Of Perigee', 'Rightessention', 'Inclination', 'X', 'Y', 'Z', 'VX', 'VY', 'VZ', 'RMAG', 'VMAG', 'Orbit Period'};
            app.yAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @yAxisDropDownValueChanged, true);
            app.yAxisDropDown.Value = 'Semi Majoraxis';
            app.TimeStepDropDownLabel = uilabel(app.Panel);
            app.TimeStepDropDownLabel.Text = 'TimeStep';
            app.TimeStepDropDown = uidropdown(app.Panel);
            app.TimeStepDropDown.Items = {'Seconds', 'Minutes', 'Hours', 'Days', 'years'};
            app.TimeStepDropDown.Value = 'Seconds';
            app.plotButton = uibutton(app.Panel, 'push');
            app.plotButton.ButtonPushedFcn = createCallbackFcn(app, @plotButtonPushed, true);
            app.plotButton.Icon = 'rt_XYPlot.png';
            app.plotButton.Text = 'plot';
            app.VariationsSwitchLabel = uilabel(app.Panel);
            app.VariationsSwitchLabel.Text = 'Variations';
            app.VariationsSwitch = uiswitch(app.Panel, 'slider');
            app.VariationsSwitch.Items = {'0', '1'};
            app.VariationsSwitch.ValueChangedFcn = createCallbackFcn(app, @VariationsSwitchValueChanged, true);
            app.VariationsSwitch.Value = '0';
            app.PlotallButton = uibutton(app.Panel, 'push');
            app.PlotallButton.ButtonPushedFcn = createCallbackFcn(app, @PlotallButtonPushed, true);
            app.PlotallButton.Icon = 'rt_XYPlot.png';
            app.PlotallButton.Text = 'Plot all';
            % Show the figure after all components are created
            app.PlotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = xyplot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotUIFigure)
        end
    end
end